classdef terpunkto3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        NumberofpointstosampleeitherforentirecloudorforeachclassLabel  matlab.ui.control.Label
        NumberofpointstosampleEditField  matlab.ui.control.NumericEditField
        CalculatecolorfeaturesCheckBox  matlab.ui.control.CheckBox
        ScalesforheirarchicaljustenterminandmaxEditFieldLabel  matlab.ui.control.Label
        scales                          matlab.ui.control.EditField
        NeighborhoodMethodButtonGroup   matlab.ui.container.ButtonGroup
        Heir                            matlab.ui.control.RadioButton
        RadiusButton                    matlab.ui.control.RadioButton
        CalculateintensityfeaturesCheckBox  matlab.ui.control.CheckBox
        SubsampleschemeButtonGroup      matlab.ui.container.ButtonGroup
        entire                          matlab.ui.control.RadioButton
        eachclass                       matlab.ui.control.RadioButton
        XindSpinnerLabel                matlab.ui.control.Label
        Xind                            matlab.ui.control.Spinner
        YindSpinnerLabel                matlab.ui.control.Label
        Yind                            matlab.ui.control.Spinner
        ZindSpinnerLabel                matlab.ui.control.Label
        Zind                            matlab.ui.control.Spinner
        redSpinnerLabel                 matlab.ui.control.Label
        red                             matlab.ui.control.Spinner
        greenSpinnerLabel               matlab.ui.control.Label
        green                           matlab.ui.control.Spinner
        blueSpinnerLabel                matlab.ui.control.Label
        blue                            matlab.ui.control.Spinner
        NxSpinnerLabel                  matlab.ui.control.Label
        Nx                              matlab.ui.control.Spinner
        NySpinnerLabel                  matlab.ui.control.Label
        Ny                              matlab.ui.control.Spinner
        NzSpinnerLabel                  matlab.ui.control.Label
        Nz                              matlab.ui.control.Spinner
        LabelSpinnerLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Spinner
        IntensitySpinnerLabel           matlab.ui.control.Label
        Intensity                       matlab.ui.control.Spinner
        PointcloudindicesLabel          matlab.ui.control.Label
        GeneratecodeandsaveinputvariablesButton  matlab.ui.control.Button
        functionEditFieldLabel          matlab.ui.control.Label
        functionEditField               matlab.ui.control.EditField
        RunterpunktoCheckBox            matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: GeneratecodeandsaveinputvariablesButton, 
        % NeighborhoodMethodButtonGroup, SubsampleschemeButtonGroup
        function GeneratecodeButtonPushed(app, event)
            inname = app.FilenameEditField.Value;
            indices = [app.Xind.Value,app.Yind.Value,app.Zind.Value,app.red.Value,app.green.Value,app.blue.Value,app.Nx.Value,app.Ny.Value,app.Nz.Value,app.Label.Value,app.Intensity.Value];
            scale = str2num(app.scales.Value);
%             app.Heir.Tag
%             app.SubsampleschemeButtonGroup.SelectedObject.Tag
            switch(app.SubsampleschemeButtonGroup.SelectedObject.Tag)
             
                case 'entire'
                    subsampleflag = 1;
                case 'eachclass'
                    subsampleflag = 0;
            end
                  
         colorflag = app.CalculatecolorfeaturesCheckBox.Value;
         intensityflag = app.CalculateintensityfeaturesCheckBox.Value;
         
%          app.NeighborhoodMethodButtonGroup.SelectedObject.Tag
         switch(app.NeighborhoodMethodButtonGroup.SelectedObject.Tag)
             case 'Heir'
                method = 2;
             case 'RadiusButton'
                method = 1;
         end
          numpts_subsample = app.NumberofpointstosampleEditField.Value;
          minscale = min(scale);
          maxscale = max(scale);
         out = ['terpunkto2(''',inname,''',[',num2str(indices),'],[',num2str(scale),'],',num2str(subsampleflag),...
             ',',num2str(intensityflag),',',num2str(colorflag),',',num2str(numpts_subsample),',',...
             num2str(method),',',num2str(minscale),',',num2str(maxscale),')'];
         app.functionEditField.Value = out;
         save('terpunkto_inputs','inname','indices','scale','subsampleflag','intensityflag','colorflag','numpts_subsample','method','minscale','maxscale')
         
         if app.RunterpunktoCheckBox.Value

            t = terpunkto2(inname,indices,scale,subsampleflag,intensityflag,colorflag,numpts_subsample,method,minscale,maxscale);

            save(['terpunkto_features_',date],'t')
            
         end
        end

        % Callback function
        function RunterpunktoButtonPushed(app, event)
            GeneratecodeButtonPushed(app,event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.NumberofpointstosampleeitherforentirecloudorforeachclassLabel = uilabel(app.UIFigure);
            app.NumberofpointstosampleeitherforentirecloudorforeachclassLabel.Text = {'Number of points to sample'; ' (either for entire cloud or for each class)'};
            app.NumberofpointstosampleEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculatecolorfeaturesCheckBox = uicheckbox(app.UIFigure);
            app.CalculatecolorfeaturesCheckBox.Text = 'Calculate color features';
            app.CalculatecolorfeaturesCheckBox.Value = true;
            app.ScalesforheirarchicaljustenterminandmaxEditFieldLabel = uilabel(app.UIFigure);
            app.ScalesforheirarchicaljustenterminandmaxEditFieldLabel.Text = {'Scales '; '(for heirarchical, just enter min and max)'};
            app.scales = uieditfield(app.UIFigure, 'text');
            app.scales.Value = '[1,0.75,0.5,0.25,0.1]';
            app.NeighborhoodMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.NeighborhoodMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GeneratecodeButtonPushed, true);
            app.NeighborhoodMethodButtonGroup.Title = 'Neighborhood Method';
            app.Heir = uiradiobutton(app.NeighborhoodMethodButtonGroup);
            app.Heir.Tag = 'Heir';
            app.Heir.Text = 'Heirarchical subsampling';
            app.Heir.Value = true;
            app.RadiusButton = uiradiobutton(app.NeighborhoodMethodButtonGroup);
            app.RadiusButton.Tag = 'RadiusButton';
            app.RadiusButton.Text = 'Radius';
            app.CalculateintensityfeaturesCheckBox = uicheckbox(app.UIFigure);
            app.CalculateintensityfeaturesCheckBox.Text = 'Calculate intensity features';
            app.SubsampleschemeButtonGroup = uibuttongroup(app.UIFigure);
            app.SubsampleschemeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GeneratecodeButtonPushed, true);
            app.SubsampleschemeButtonGroup.Title = 'Subsample scheme';
            app.entire = uiradiobutton(app.SubsampleschemeButtonGroup);
            app.entire.Tag = 'entire';
            app.entire.Text = 'Subsample entire cloud (prediction)';
            app.entire.Value = true;
            app.eachclass = uiradiobutton(app.SubsampleschemeButtonGroup);
            app.eachclass.Tag = 'eachclass';
            app.eachclass.Text = 'Subsample N pts for each class (training)';
            app.XindSpinnerLabel = uilabel(app.UIFigure);
            app.XindSpinnerLabel.Text = 'X ind';
            app.Xind = uispinner(app.UIFigure);
            app.Xind.Limits = [0 50];
            app.Xind.Value = 1;
            app.YindSpinnerLabel = uilabel(app.UIFigure);
            app.YindSpinnerLabel.Text = 'Y ind';
            app.Yind = uispinner(app.UIFigure);
            app.Yind.Limits = [0 50];
            app.Yind.Value = 2;
            app.ZindSpinnerLabel = uilabel(app.UIFigure);
            app.ZindSpinnerLabel.Text = 'Z ind';
            app.Zind = uispinner(app.UIFigure);
            app.Zind.Limits = [0 50];
            app.Zind.Value = 3;
            app.redSpinnerLabel = uilabel(app.UIFigure);
            app.redSpinnerLabel.Text = 'red';
            app.red = uispinner(app.UIFigure);
            app.red.Limits = [0 50];
            app.red.Value = 4;
            app.greenSpinnerLabel = uilabel(app.UIFigure);
            app.greenSpinnerLabel.Text = 'green';
            app.green = uispinner(app.UIFigure);
            app.green.Limits = [0 50];
            app.green.Value = 5;
            app.blueSpinnerLabel = uilabel(app.UIFigure);
            app.blueSpinnerLabel.Text = 'blue';
            app.blue = uispinner(app.UIFigure);
            app.blue.Limits = [0 50];
            app.blue.Value = 6;
            app.NxSpinnerLabel = uilabel(app.UIFigure);
            app.NxSpinnerLabel.Text = 'Nx';
            app.Nx = uispinner(app.UIFigure);
            app.Nx.Limits = [0 50];
            app.Nx.Value = 7;
            app.NySpinnerLabel = uilabel(app.UIFigure);
            app.NySpinnerLabel.Text = 'Ny';
            app.Ny = uispinner(app.UIFigure);
            app.Ny.Limits = [0 50];
            app.Ny.Value = 8;
            app.NzSpinnerLabel = uilabel(app.UIFigure);
            app.NzSpinnerLabel.Text = 'Nz';
            app.Nz = uispinner(app.UIFigure);
            app.Nz.Limits = [0 50];
            app.Nz.Value = 9;
            app.LabelSpinnerLabel = uilabel(app.UIFigure);
            app.Label = uispinner(app.UIFigure);
            app.Label.Limits = [0 50];
            app.Label.Value = 10;
            app.IntensitySpinnerLabel = uilabel(app.UIFigure);
            app.IntensitySpinnerLabel.Text = 'Intensity';
            app.Intensity = uispinner(app.UIFigure);
            app.Intensity.Limits = [0 50];
            app.Intensity.Value = 11;
            app.PointcloudindicesLabel = uilabel(app.UIFigure);
            app.PointcloudindicesLabel.Text = 'Point cloud indices';
            app.GeneratecodeandsaveinputvariablesButton = uibutton(app.UIFigure, 'push');
            app.GeneratecodeandsaveinputvariablesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratecodeButtonPushed, true);
            app.GeneratecodeandsaveinputvariablesButton.Text = 'Generate code and save input variables';
            app.functionEditFieldLabel = uilabel(app.UIFigure);
            app.functionEditFieldLabel.Text = 'function';
            app.functionEditField = uieditfield(app.UIFigure, 'text');
            app.RunterpunktoCheckBox = uicheckbox(app.UIFigure);
            app.RunterpunktoCheckBox.Text = 'Run terpunkto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = terpunkto3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end